classdef GUI_Ver_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        RunTestButton               matlab.ui.control.Button
        NumberofIPsSpinnerLabel     matlab.ui.control.Label
        NumberofIPsSpinner          matlab.ui.control.Spinner
        IperfTypeDropDownLabel      matlab.ui.control.Label
        IperfTypeDropDown           matlab.ui.control.DropDown
        NumberofTrialsSpinnerLabel  matlab.ui.control.Label
        NumberofTrialsSpinner       matlab.ui.control.Spinner
        TestFolderEditFieldLabel    matlab.ui.control.Label
        TestFolderEditField         matlab.ui.control.EditField
        FileNameEditFieldLabel      matlab.ui.control.Label
        FileNameEditField           matlab.ui.control.EditField
        DurationSpinner_2Label      matlab.ui.control.Label
        DurationSpinner             matlab.ui.control.Spinner
        IperfServerEditFieldLabel   matlab.ui.control.Label
        IperfServerEditField        matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NumberofIPsSpinner
        function NumberofIPsSpinnerValueChanged(app, event)
            app.NumberofIPsSpinner.Value= event.Value;
            
        end

        % Value changed function: IperfTypeDropDown
        function IperfTypeDropDownValueChanged(app, event)
            app.IperfTypeDropDown.Value = event.Value;
            
        end

        % Value changed function: NumberofTrialsSpinner
        function NumberofTrialsSpinnerValueChanged(app, event)
            app.NumberofTrialsSpinner.Value = event.Value;
            
        end

        % Value changed function: TestFolderEditField
        function TestFolderEditFieldValueChanged(app, event)
            app.TestFolderEditField.Value = event.Value;
            
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            app.FileNameEditField.Value = event.Value;
            
        end

        % Button pushed function: RunTestButton
        function RunTestButtonPushed(app, event)
            iperf_serv = app.IperfServerEditField.Value;
            dir = app.TestFolderEditField.Value;
            system(strcat("mkdir ", dir));
            % Creates a directory folder to store scenario
            num_IPs = string(app.NumberofIPsSpinner.Value);
            iperf_type = app.IperfTypeDropDown.Value;
            num_trials = string(app.NumberofTrialsSpinner.Value);
            dur = string(app.DurationSpinner.Value);
            filename = app.FileNameEditField.Value;
            cmdStr = strcat("iperf_bash.sh"," ",iperf_serv," ",num_IPs," ",iperf_type," ",num_trials, " ", dur, " ", dir," ",filename);
            system(cmdStr);
            
        end

        % Value changed function: IperfServerEditField
        function IperfServerEditFieldValueChanged(app, event)
            app.IperfServerEditField.Value = event.Value;
            
        end

        % Value changing function: IperfServerEditField
        function IperfServerEditFieldValueChanging(app, event)
            app.IperfServerEditField.Value = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunTestButton = uibutton(app.UIFigure, 'push');
            app.RunTestButton.ButtonPushedFcn = createCallbackFcn(app, @RunTestButtonPushed, true);
            app.RunTestButton.Text = 'Run Test';
            app.NumberofIPsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofIPsSpinnerLabel.Text = 'Number of IPs';
            app.NumberofIPsSpinner = uispinner(app.UIFigure);
            app.NumberofIPsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofIPsSpinnerValueChanged, true);
            app.NumberofIPsSpinner.Value = 5;
            app.IperfTypeDropDownLabel = uilabel(app.UIFigure);
            app.IperfTypeDropDownLabel.Text = 'Iperf Type';
            app.IperfTypeDropDown = uidropdown(app.UIFigure);
            app.IperfTypeDropDown.Items = {'-c', '-d', '-r'};
            app.IperfTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @IperfTypeDropDownValueChanged, true);
            app.IperfTypeDropDown.Value = '-c';
            app.NumberofTrialsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofTrialsSpinnerLabel.Text = 'Number of Trials';
            app.NumberofTrialsSpinner = uispinner(app.UIFigure);
            app.NumberofTrialsSpinner.Limits = [1 Inf];
            app.NumberofTrialsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofTrialsSpinnerValueChanged, true);
            app.NumberofTrialsSpinner.Value = 5;
            app.TestFolderEditFieldLabel = uilabel(app.UIFigure);
            app.TestFolderEditFieldLabel.Text = 'Test Folder';
            app.TestFolderEditField = uieditfield(app.UIFigure, 'text');
            app.TestFolderEditField.ValueChangedFcn = createCallbackFcn(app, @TestFolderEditFieldValueChanged, true);
            app.TestFolderEditField.Value = 'Scenario';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.FileNameEditField.Value = 'Test';
            app.DurationSpinner_2Label = uilabel(app.UIFigure);
            app.DurationSpinner_2Label.Text = 'Duration';
            app.DurationSpinner = uispinner(app.UIFigure);
            app.DurationSpinner.Limits = [1 Inf];
            app.DurationSpinner.Value = 10;
            app.IperfServerEditFieldLabel = uilabel(app.UIFigure);
            app.IperfServerEditFieldLabel.Text = 'Iperf  Server';
            app.IperfServerEditField = uieditfield(app.UIFigure, 'text');
            app.IperfServerEditField.ValueChangedFcn = createCallbackFcn(app, @IperfServerEditFieldValueChanged, true);
            app.IperfServerEditField.ValueChangingFcn = createCallbackFcn(app, @IperfServerEditFieldValueChanging, true);
            app.IperfServerEditField.Value = '10.1.101.2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Ver_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end